classdef DeltaDesign < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        deltaspace             matlab.ui.control.UIAxes
        CompositionPanel       matlab.ui.container.Panel
        UP                     matlab.ui.control.Button
        DOWN                   matlab.ui.control.Button
        ANC                    matlab.ui.control.Button
        RevisePanel            matlab.ui.container.Panel
        MOVE                   matlab.ui.control.Button
        DEL                    matlab.ui.control.Button
        FLIP                   matlab.ui.control.Button
        CHCOLOR                matlab.ui.control.Button
        FCTRL                  matlab.ui.control.Button
        DeltaWorldStatusPanel  matlab.ui.container.Panel
        DeltaStatus            matlab.ui.control.TextArea
        ControlsPanel          matlab.ui.container.Panel
        UndoButton             matlab.ui.control.Button
        RedoButton             matlab.ui.control.Button
        RefreshButton          matlab.ui.control.Button
        ClearButton            matlab.ui.control.Button
        Feedback               matlab.ui.container.Panel
        FOS                    matlab.ui.control.TextArea
        COST                   matlab.ui.control.TextArea
        DoorLength             matlab.ui.control.TextArea
        Area                   matlab.ui.control.TextArea
        Score                  matlab.ui.control.TextArea
        FOSLabel               matlab.ui.control.Label
        COSTLabel              matlab.ui.control.Label
        DoorLengthLabel        matlab.ui.control.Label
        AreaLabel              matlab.ui.control.Label
        ScoreLabel             matlab.ui.control.Label
        ThermalLabel           matlab.ui.control.Label
        thermal                matlab.ui.control.TextArea
        AestheticsLabel        matlab.ui.control.Label
        aesthetics             matlab.ui.control.TextArea
        Details                matlab.ui.control.TextArea
        EndStudyButton         matlab.ui.control.Button
        Panel                  matlab.ui.container.Panel
        Label                  matlab.ui.control.Label
        Label_2                matlab.ui.control.Label
        MinimumRequirementsandSuggestionsLabel  matlab.ui.control.Label
        Label_3                matlab.ui.control.Label
        Image                  matlab.ui.control.Image
    end

    
    properties (Access = public)
        but % Defines which button is pressed 
        world = deltaworld(); % Main WORLD 
        tri_obj  = triangle(); % Triangle object containing additional functions
        iter = 0; % Iteration Number
        Current_tri =  0;% Triangle selected
        myTimer = 0;
        TIMER ;
        total_time = 45;
        Time_left = 45;
        participant = 51; 
        Prompt;
        action = [];
        action_detail = string()
        timestamp = [];
        curpos = [];
        max_actions = NaN;
        customer_satisfaction = 0; % Rea 
        dir = string()
        test_case = 0;
    end
    
    methods (Access = public)
        
        % use to display remaining time 
        function time_control(app,~,~)
            if app.iter > 0
                    a = app.myTimer;
                    b = now;  
                    d = datetime(b,'ConvertFrom','datenum');
                    c=  d.Hour*3600 + d.Minute*60 + d.Second ;
                    app.Time_left = app.Time_left - (c-a)/60;
                    app.Details.FontColor = 'k';
            end
            
            str = "Hello Participant " + app.participant + '\n' + "Time left:  " + num2str(app.Time_left) + " Minutes ";

            app.Details.Value = compose(str);
            app.Details.FontSize = 20;
            if app.Time_left <=5
                app.Details.FontColor = 'k';
            end
        end
        
        % MOVING delta to a new location
        function movetri(app,newloc,cse)
              [msg,success] = app.world.movetri(newloc,app.Current_tri);
              if success == 1
                if cse == 1
                    app.Current_tri = 0;
                    app.but = 'Nothing';
                    app.action_detail(app.iter) = "Moved Triangle " ;
                    app.action(app.iter) = 4; %MOVE
                    app.timestamp(app.iter) = app.Time_left;
                end                 
                    app.DeltaStatus.Value= msg;
                    app.iter = app.iter + 1;
                    update_plot(app) ;
                    save_delta(app);
                    obj_delta(app);
                    app.action_detail(app.iter) = "Fine Control Triangle " ;
                    app.action(app.iter) = 9; % Fine MOVE  
                    app.timestamp(app.iter) = app.Time_left;                                
              else
                app.but = 'Nothing';
                app.DeltaStatus.Value = msg;
                app.Current_tri = 0;
                update_plot(app)
              end  
        end
        
        % ---------DRAWING DELTAS--------
        function draw_tri(app,x,y,ori,colr,casenumber)

            if ori == 1 %UP Triangle
                Vert = [ x-2 y-2; x y+2; x+2 y-2];
            else
                Vert = [ x-2 y+2; x y-2; x+2 y+2];
            end
            Tri = [1 2 3];
            %b = 'k';
            if colr == 1
                c = 'r'; %RED
            elseif colr ==0
                c = 'b'; %BLUE
            elseif colr ==3
                c = 'g'; % Green for highlight
            end
            %Finally Draw the triangles
            hold(app.deltaspace,'on');
            f = patch(app.deltaspace,'Faces',Tri,'Vertices',Vert,'FaceColor',c,'EdgeColor','k');
            f.EdgeAlpha = 0.4;
            if casenumber == 4
                hold(app.deltaspace,'on')
                plot(app.deltaspace,x,y,'w.','MarkerSize',50)
            end
            if casenumber ==5
                f.EdgeColor = 'k';
                f.EdgeAlpha = 1;
                f.LineWidth = 3;
            end

        end
        
        % UPDATE THE PLOT ---------------------------
        function update_plot(app,~) 
            app.deltaspace.cla;            
            axes = app.world.axes;
            axno = app.world.axno;
            for i = 1:4:length(axno)
               x = axes(:,1,i);
               y = axes(:,2,i);
               plot(app.deltaspace,x,y,'Color',[0.9 0.9 0.9],'LineStyle','-');
               hold(app.deltaspace,'on');    
            end
            k = find(axno==0);
            plot(app.deltaspace,axes(:,1,k),axes(:,2,k),'k');
            hold(app.deltaspace,'on');
            Vert = app.world.Vertices;
            plot(app.deltaspace,Vert(:,1),Vert(:,2),'b.','MarkerSize',4);
            hold(app.deltaspace,'on');
                if norm(app.curpos)>0
                        hold(app.deltaspace,'on')
                        loc = app.curpos;
                        draw_tri(app,loc(1),loc(2),1,1,4);                 
                         
                end                        
                if app.world.number > 1
                    %CASE1 and CASE2
                    for i = 1:app.world.number-1
                        loc = app.world.coordinates(i,:);
                        ori = app.world.orientation(i);
                        colr = app.world.color(i);
                        draw_tri(app,loc(1),loc(2),ori,colr,1);
                        hold(app.deltaspace,'on')                         
                     
                    end                       
                 
                end
                %Case COG EXIST
                if abs(app.world.COGax) >0
                    hold(app.deltaspace,'on')
                    plot(app.deltaspace,app.world.COG(1),app.world.COG(2),'g.','MarkerSize',40);
                end  
                
                % Door
                 if length(app.world.door)>1 && app.world.anc ==2 
                    for i = 1:length(app.world.door)
                        hold(app.deltaspace,'on')
                        k = app.world.door(i);   
                        loc = app.world.coordinates(k,:);
                        ori = app.world.orientation(k);
                        colr = app.world.color(k);
                        draw_tri(app,loc(1),loc(2),ori,colr,5);
                    end
                    
                end
                % Triangle Anchor CASE4
                if app.world.anc ==2 
                    %triangles = app.world.tri_list;
                    temp = app.world.anchor();
                    anchors = find(temp == 1);
                    for i = 1:app.world.anc
                        hold(app.deltaspace,'on') 
                         k = anchors(i);
                        loc = app.world.coordinates(k,:);
                        ori = app.world.orientation(k);
                        colr = app.world.color(k);
                        draw_tri(app,loc(1),loc(2),ori,colr,4);
                    end
                end              


                % Highlight Triangle CASE3
                if app.Current_tri > 0 && app.world.number > 1
                    %triangles = app.world;
                    k = app.Current_tri;
                    hold(app.deltaspace,'on')
                    loc = app.world.coordinates(k,:);
                    ori = app.world.orientation(k);
                    draw_tri(app,loc(1),loc(2),ori,3,3);
                end
                    hold(app.deltaspace,'off')                    

        end
        
               
        % EVALUATE THE DESIGN ----------------------------
 
        function obj_delta(app)
                %app.iter
                %cd Participant1
                app.max_actions = length(app.action);                
                if app.world.number > 1 && app.world.anc ==2
                [result,exist] = app.world.cal_objfun();
                    if exist == 1
                        app.FOS.Value = compose(""+ result.Strength_Performance);
                        app.COST.Value = compose(""+ result.Cost_Performance);
                        app.DoorLength.Value = compose(""+ result.door_length);
                        app.Score.Value = compose(""+ app.world.score);
                        app.Area.Value = compose(""+ result.area); 
                        app.aesthetics.Value = compose("" + result.Aesthetic_Performance);
                        app.thermal.Value = compose("" + result.Thermal_Performance);

%                          str = "FOS: " + result.Strength_Performance + " , Cost:  " + result.Cost_Performance ...
%                             + '\n' +"Design is thermally: 1" + ", and Aesthetically: 1" + ...
%                             ", Inside area of the structure is  " + app.world.Inside_Area/2 + ", Door Length is " + door_length + "m"  + '\n'...                       
%                             + "Design Score is : " + app.world.SCORE;
%                          app.FOS.Value = compose(str);
                    else
                        error1 = result.tricheck.errortype1;
                        error2 = result.tricheck.errortype2;  
                        
                        if length(error1) ~=2
                            msg = "There are more or less than two door triangles";
                        elseif ~isempty(error2)
                            msg = "Triangles not connected properly";
                        elseif ~isempty(error2) && length(error1) ~=2
                            msg = "more or less than two door deltas and delta not fully closed";
                        end
                            
                        app.Score.Value = " Consider " + msg;
                    end
                else
                     app.Score.Value = "--" ;
                end 

        end
        
        % SAVE the DELTA -----------------------
        function save_delta(app)
                if app.iter > 0
                    action_data.actions = app.action;
                    action_data.details = app.action_detail;
                    action_data.timestamp = app.total_time - app.timestamp;
                    fname = app.dir + app.participant+ "actiondata";
                    save(fname,'-struct','action_data')
                end
                clear state
                state = app.world;
                filename = app.dir + app.participant + "State_" + app.iter;
                filename = char(filename);                
                save(filename,'state');
        end
        
        % REFRESH APP ------------------------
        function refresh_app(app)
            app.Current_tri = 0;
            
           if app.world.number ==2
                    t = now;  
                    d = datetime(t,'ConvertFrom','datenum');
                    app.myTimer = d.Hour*3600 + d.Minute*60 + d.Second;
           end
            save_delta(app)
            obj_delta(app)
            update_plot(app)                       
        end
        
        
        function run_actions(app,loc,click)          
            
            if loc(1) > 0 && loc(1) < max(app.world.Vertices(:,1)) && loc(2) > 0 && loc(2) < max(app.world.Vertices(:,2))
                
                if strcmp(click,'normal')
                            colr = 'r' ;% left click RED COLOR
                elseif strcmp(click,'extend')
                            app.but = 'Nothing';
                elseif strcmp(click,'alt') 
                            colr = 'b' ;%rightclick or ctrl+left click
    
                else
                            app.DeltaStatus.Value = 'Try Again';
                end   
                
                % ADD DELTA UP--------------------
                if strcmp(app.but, 'DeltaUP')
                    
                        [msg,success] = app.world.add_delta(loc,colr,1);
                        if success == 1
                            app.but = 'Nothing';
                            app.DeltaStatus.Value = msg;
                            app.DeltaStatus.FontColor = colr;
                            app.iter = app.iter + 1;
                            app.action_detail(app.iter) = colr + " UP Delta at" + loc(1) + " "  + loc(2);
                            app.action(app.iter) = 1;
                            app.timestamp(app.iter) = app.Time_left;
                            refresh_app(app)
%                             save_delta(app);
%                             update_plot(app);
                        else
                            app.but = 'DeltaUP';
                            app.DeltaStatus.Value = msg;
                        end
                        
                % ADD DELTA DOWN-------------------------
                elseif strcmp(app.but, 'DeltaDOWN')
                    
                        [msg,success] = app.world.add_delta(loc,colr,0);
                        if success == 1
                            app.but = 'Nothing';
                            app.DeltaStatus.Value = msg;
                            app.DeltaStatus.FontColor = colr;                            
                            app.iter = app.iter + 1;
                            app.action_detail(app.iter) = colr + " DOWN Delta" + loc(1) + " "  + loc(2) ;
                            app.action(app.iter) = 2;
                            app.timestamp(app.iter) = app.Time_left;
                            refresh_app(app)
                        else
                            app.but = 'DeltaUP';
                            app.DeltaStatus.Value = msg;
                        end
                     % DELETE------------------------
                elseif strcmp(app.but, 'Delete')
                        number = app.world.number;
                        if number > 1
                            [msg,success] = app.world.del_delta(loc);
                            if success == 1
                                app.but = 'Nothing';
                                app.DeltaStatus.Value= msg;
                                app.Current_tri = 0;
                            else
                                app.but = 'Delete';
                                app.DeltaStatus.Value = msg;
                            end                            
                            app.iter = app.iter + 1;
                            app.action_detail(app.iter) = "Deleted Triangle " + loc(1) + " " +  loc(2);
                            app.action(app.iter) = 3;
                            app.timestamp(app.iter) = app.Time_left;
                            refresh_app(app)
                        else
                            app.DeltaStatus.Value = "Draw a triangle first";
                            app.but = 'Nothing';
                        end
                elseif strcmp(app.but, 'Move1')
                        number = app.world.number;
                        app.DeltaStatus.Value = "Select a triangle to move";  
                        if number > 1
                            locd = app.tri_obj.nearposspoint2(loc(1),loc(2),app.world.Vertices);
                            app.Current_tri = app.tri_obj.select_tri(locd(1),locd(2),app.world.coordinates,app.world.orientation,number);
                            if app.Current_tri >0
                                update_plot(app);
                                app.DeltaStatus.Value = "Select the new location for this triangle";
                                app.but = 'Move2';
                            else
                                app.but = 'Move1';
                                app.DeltaStatus.Value = "Select a triangle";
                            end
                        else
                            app.DeltaStatus.Value = "Draw a triangle first";
                            app.but = 'Nothing';
                        end 
                elseif strcmp(app.but, 'Move2')
                            movetri(app,loc,1) ;
                            
                elseif strcmp(app.but, 'FineCTRL')
                        number = app.world.number;
                        app.DeltaStatus.Value = "Select a triangle to move";  
                        if number > 1
                            locd = app.tri_obj.nearposspoint2(loc(1),loc(2),app.world.Vertices);
                            app.Current_tri = app.tri_obj.select_tri(locd(1),locd(2),app.world.coordinates,app.world.orientation,number);
                            if app.Current_tri >0
                                update_plot(app);
                                app.DeltaStatus.Value = "Select the new location, use arrow keys for fine control. Use Escape when done";
%                                 app.action_detail(app.iter) = "Fine Control Triangle " ;
%                                 app.action(app.iter) = 9; % Fine MOVE                                
                            else
                                app.but = 'FineCTRL';
                                app.DeltaStatus.Value = "Select a triangle";
                            end
                        else
                            app.DeltaStatus.Value = "Draw a triangle first";
                            app.but = 'Nothing';
                        end 
                        
                 % FLIP------------------------------
    
                elseif strcmp(app.but, 'Flip')
                    app.DeltaStatus.Value = "Select a triangle for flip";
                    if app.world.number > 1
                            locd = app.tri_obj.nearposspoint2(loc(1),loc(2),app.world.Vertices);
                            app.Current_tri = app.tri_obj.select_tri(locd(1),locd(2),app.world.coordinates,app.world.orientation,app.world.number);
                            if app.Current_tri >0
                                  [msg,success] = app.world.flip(app.Current_tri);
                                  if success == 1
                                    app.but = 'Nothing';
                                    app.Current_tri = 0;
                                    app.DeltaStatus.FontColor = 'k';
                                    app.DeltaStatus.Value= msg;                                  
                                    app.iter = app.iter + 1;
                                    app.action_detail(app.iter) = "FLIP Triangle " + app.Current_tri;
                                    app.action(app.iter) = 5; %MOVE
                                    app.timestamp(app.iter) = app.Time_left;
                                    refresh_app(app)
                                                    
                                  else
                                    app.but = 'Nothing';
                                    app.DeltaStatus.Value = msg;
                                    app.Current_tri = 0;
                                    refresh_app(app)
                                  end  
                            else
                                app.but = 'Flip';
                                app.DeltaStatus.Value = "Select a triangle";
                            end
                    else
                        app.DeltaStatus.Value = "Draw some triangles ";
                    end
                    
                % CHANGE COLOR --------------------------------------------
                elseif strcmp(app.but, 'Color')
                    app.DeltaStatus.Value = "Select a triangle for changing color";
                    if app.world.number > 1
                            locd = app.tri_obj.nearposspoint2(loc(1),loc(2),app.world.Vertices);
                            app.Current_tri = app.tri_obj.select_tri(locd(1),locd(2),app.world.coordinates,app.world.orientation,app.world.number);
                            if app.Current_tri >0
                                  [msg,success] = app.world.changecolor(app.Current_tri);
                                  if success == 1
                                    app.but = 'Nothing';
                                    app.Current_tri = 0;
                                    app.DeltaStatus.Value= msg;
                                    app.iter = app.iter + 1;
                                    app.action_detail(app.iter) = "COLOR Triangle " + app.Current_tri;
                                    app.action(app.iter) = 6; %ColorE
                                    app.timestamp(app.iter) = app.Time_left;
                                    refresh_app(app)
                                  else
                                    app.but = 'Nothing';
                                    app.DeltaStatus.Value = msg;
                                    app.Current_tri = 0;
                                    update_plot(app)
                                  end  
                            else
                                app.but = 'Flip';
                                app.DeltaStatus.Value = "Select a triangle";
                            end
                    else
                        app.DeltaStatus.Value = "Draw some triangles";
                    end
                    
                % ANCHOR ---------------------------------------------
                    
                elseif strcmp(app.but, 'Anchor')
                       app.DeltaStatus.Value = "Select two deltas, where you want to add anchor";
                       app.world.anchor = zeros(app.world.number);
                       app.world.anc = 0;
                       update_plot(app);
                       if app.world.number>2
                            locd = app.tri_obj.nearposspoint2(loc(1),loc(2),app.world.Vertices);
                            app.Current_tri = app.tri_obj.select_tri(locd(1),locd(2),app.world.coordinates,app.world.orientation,app.world.number);
                            if app.Current_tri > 0
                                app.world.anc = 1;
                                app.world.anchor(app.world.anc) = app.Current_tri;
                                [msg,~] = app.world.add_anchor(app.Current_tri,app.world.anc);
                                app.but = 'Anchor2';                         
                                app.DeltaStatus.Value = msg + ", Add Second anchor";
                                %save_delta(app)
                                app.Current_tri = 0;
                                update_plot(app);
                            else
                                app.DeltaStatus.Value = "Try again";
                                app.but = 'Anchor';
                            end
                       else
                            app.DeltaStatus.Value = "Adding anchor requires a minimum of two triangles";
                       end

                elseif strcmp(app.but,'Anchor2')
                         
                         locd = app.tri_obj.nearposspoint2(loc(1),loc(2),app.world.Vertices);
                        app.Current_tri = app.tri_obj.select_tri(locd(1),locd(2),app.world.coordinates,app.world.orientation,app.world.number);
                        if app.Current_tri > 0
                                app.world.anc = 2;
                                app.world.anchor(app.world.anc) = app.Current_tri;
                                [msg,~] = app.world.add_anchor(app.Current_tri,app.world.anc);                                
                                app.but = 'Nothing';
                                %update_plot(app);
                                app.DeltaStatus.Value = msg;
                                %save_delta(app)                                
                                    app.iter = app.iter + 1;
                                    app.action_detail(app.iter) = "Anchor Triangle " ;
                                    app.action(app.iter) = 7; %Anchor
                                    app.timestamp(app.iter) = app.Time_left;
                                    refresh_app(app)
                        else
                                app.DeltaStatus.Value = "Try again";
                                app.but = 'Anchor2';
                        end
                
                elseif strcmp(app.but,'Nothing')
                        app.Current_tri = 0;
%                         update_plot(app)
                end
            else                                          
               app.DeltaStatus.Value = "Click inside the area";
            end         
                   
           
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, prompt)
            %world = deltaworld();
            %app.Prompt = Opener(app, app.participant); 
            if nargin == 0
                prompt = 51;
            end
            app.participant = prompt;
            app.DeltaStatus.Value = "Welcome to delta design world ";
            app.world.deltaenv(60);
            app.dir = "data/" + app.participant + "/";
            fname = app.dir + app.participant+ "actiondata.mat";
            if isfile(fname)
                load(fname,'actions')
                app.action = actions;
                app.iter = length(actions);
                load(app.dir + app.participant + "State_" + app.iter + ".mat",'state')
                app.world = state;
                
                refresh_app(app);
                app.DeltaStatus.Value = "Refreshed";
            else
                app.iter = 0;
                mkdir(app.dir)
            end
            app.but= 'Nothing';
            app.DeltaStatus.FontSize = 14;
            app.Score.Value = "Design a residence";
            app.Score.FontSize = 18;
            app.FOS.FontSize = 18;
            app.COST.FontSize = 18;
            app.DoorLength.FontSize = 18;
            app.Area.FontSize = 18;
            app.thermal.FontSize = 18;
            app.aesthetics.FontSize = 18;
            refresh_app(app)
            app.TIMER = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', 1, ...                     % Period is 1 second
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.time_control);    % Specify callback function
            start(app.TIMER);
%             update_plot(app)
%             save_delta(app)

        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(app, event)
                 if app.iter > 0
                     %update_app(app);
                     prev_state = app.iter - 1;
                     filename = app.dir + app.participant + "State_" + prev_state;
                     filename = char(filename);
                     load(filename,'state')
                     app.world = state;
                     update_plot(app);
                     obj_delta(app);
                     app.iter = app.iter - 1;   
                     app.DeltaStatus.Value = "Undo";
                 else
                     app.DeltaStatus.Value = "Undo Not possible";
                 end
        end

        % Button pushed function: RedoButton
        function RedoButtonPushed(app, event)
                   
                 if app.iter < app.max_actions
                     update_plot(app)
                     next_state = app.iter + 1;
                     filename = app.dir + app.participant + "State_" + next_state;
                     filename = char(filename);
                     load(filename,'state')
                     app.world = state; 
                     update_plot(app);
                     obj_delta(app);
                     app.iter = app.iter + 1;   
                     app.DeltaStatus.Value = "Redo";                  
                    
                 else
                     app.DeltaStatus.Value = "Redo Not possible";
                 end
                 
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case 'w'
                    UPPushed(app, event);
                case 's'
                    DOWNPushed(app, event);
                case 'delete'
                    DELPushed(app, event);           
                case 'd'
                    MOVEPushed(app, event);
                case 'e'
                    FCTRLPushed(app, event);
                case 'f'
                    FLIPPushed(app, event);
                case 'c'
                    CHCOLORPushed(app, event);
                case 'uparrow'
                    if app.Current_tri > 0
                        locd = app.world.coordinates(app.Current_tri,:);
                        newloc = [locd(1) locd(2) + 1];                 
                        movetri(app,newloc,0)
                    end
                        
                case 'downarrow'
                    if app.Current_tri > 0
                        locd = app.world.coordinates(app.Current_tri,:);
                        newloc = [locd(1) locd(2) - 1];                 
                        movetri(app,newloc,0)
                    end                  
                case 'leftarrow'
                    if app.Current_tri > 0
                        locd = app.world.coordinates(app.Current_tri,:);
                        newloc = [locd(1)-0.5 locd(2)];                 
                        movetri(app,newloc,0)
                    end
                case 'rightarrow'
                    if app.Current_tri > 0
                        locd = app.world.coordinates(app.Current_tri,:);
                        newloc = [locd(1)+0.5 locd(2)];                 
                        movetri(app,newloc,0)
                    end   
                case 'escape'
                    app.Current_tri = 0;
                    refresh_app(app);                  
                   
                    %Change Color                
            end
        end

        % Value changed function: DeltaStatus
        function DeltaStatusValueChanged(app, event)
            %value = app.DeltaStatus.Value;            
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            %world = deltaworld();
            %hobjects = findobj(gca,'Type','patch');
            if app.test_case == 0
                temp = app.deltaspace.CurrentPoint; % Returns 2x3 array of points
                loc = [temp(1,1) temp(1,2)]; % Gets the (x,y) coordinates
                click = app.UIFigure.SelectionType;
                app.run_actions(loc,click);
            end
%           
       
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            

        end

        % Value changed function: FOS
        function FOSValueChanged(app, event)
            
        end

        % Button pushed function: UP
        function UPPushed(app, event)
                app.but = 'DeltaUP';   
                app.DeltaStatus.Value = "Click on point to add UP triangle";
                app.DeltaStatus.FontColor = 'k'; 

        end

        % Button pushed function: DOWN
        function DOWNPushed(app, event)
                 app.but = 'DeltaDOWN';%  
                 app.DeltaStatus.Value = "Click on point to add Down triangle";
                 app.DeltaStatus.FontColor = 'k';

        end

        % Button pushed function: DEL
        function DELPushed(app, event)
            app.but = 'Delete';
            app.DeltaStatus.Value = "Select a triangle to delete";
            app.DeltaStatus.FontColor = 'k';
        end

        % Button pushed function: MOVE
        function MOVEPushed(app, event)
            app.but = 'Move1';
            app.DeltaStatus.Value = "Select a triangle to Move";
            app.DeltaStatus.FontColor = 'k';
        end

        % Button pushed function: FCTRL
        function FCTRLPushed(app, event)
            app.but = 'FineCTRL';
            app.DeltaStatus.Value = "Select a triangle to Move (Finer Control)";
            app.DeltaStatus.FontColor = 'k';
        end

        % Button pushed function: ANC
        function ANCPushed(app, event)
            app.but = 'Anchor';
        end

        % Button pushed function: CHCOLOR
        function CHCOLORPushed(app, event)
            app.but = 'Color';
        end

        % Button pushed function: FLIP
        function FLIPPushed(app, event)
            app.but = "Flip";
            app.DeltaStatus.Value = "Select a triangle to flip";
            app.DeltaStatus.FontColor = 'k';
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
                app.but = "Nothing";
                app.Current_tri = 0;
                refresh_app(app)
                app.DeltaStatus.FontColor = 'm';
                app.DeltaStatus.Value = "Refreshed";
                
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.world = deltaworld();            
            app.DeltaStatus.Value = 'Cleared';
            % app.DeltaStatus.Value = "Welcome to delta design world ";
            app.world.deltaenv(60); 
            %app.iter = 0;
            app.but= 'Nothing';
            app.DeltaStatus.FontSize = 18;
            refresh_app(app)
            % Dialogue Prompt
            % Delete all triangles,anchors everything
            % Delete all files
        end

        % Value changed function: Details
        function DetailsValueChanged(app, event)
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
                delete(app)
                %delete(app.Prompt)
        end

        % Button pushed function: EndStudyButton
        function EndStudyButtonPushed(app, event)
                app.DeltaStatus.Value = "THANK YOU" ;
                stop(app.TIMER);
                UIFigureCloseRequest(app,event)
                 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.deltaspace = uiaxes(app.UIFigure);
            title(app.deltaspace, '')
            app.deltaspace.XTick = [];
            app.deltaspace.YTick = [];
            app.CompositionPanel = uipanel(app.UIFigure);
            app.CompositionPanel.Title = 'Composition';
            app.UP = uibutton(app.CompositionPanel, 'push');
            app.UP.ButtonPushedFcn = createCallbackFcn(app, @UPPushed, true);
            app.UP.Icon = 'Triangle UP.png';
            app.UP.Text = '';
            app.DOWN = uibutton(app.CompositionPanel, 'push');
            app.DOWN.ButtonPushedFcn = createCallbackFcn(app, @DOWNPushed, true);
            app.DOWN.Icon = 'Triangle D.png';
            app.DOWN.Text = '';
            app.ANC = uibutton(app.CompositionPanel, 'push');
            app.ANC.ButtonPushedFcn = createCallbackFcn(app, @ANCPushed, true);
            app.ANC.Icon = 'Anchor.png';
            app.ANC.Text = '';
            app.RevisePanel = uipanel(app.UIFigure);
            app.RevisePanel.Title = 'Revise';
            app.MOVE = uibutton(app.RevisePanel, 'push');
            app.MOVE.ButtonPushedFcn = createCallbackFcn(app, @MOVEPushed, true);
            app.MOVE.Text = 'Move';
            app.DEL = uibutton(app.RevisePanel, 'push');
            app.DEL.ButtonPushedFcn = createCallbackFcn(app, @DELPushed, true);
            app.DEL.Text = 'Del';
            app.FLIP = uibutton(app.RevisePanel, 'push');
            app.FLIP.ButtonPushedFcn = createCallbackFcn(app, @FLIPPushed, true);
            app.FLIP.Text = 'Flip';
            app.CHCOLOR = uibutton(app.RevisePanel, 'push');
            app.CHCOLOR.ButtonPushedFcn = createCallbackFcn(app, @CHCOLORPushed, true);
            app.CHCOLOR.Text = 'Color';
            app.FCTRL = uibutton(app.RevisePanel, 'push');
            app.FCTRL.ButtonPushedFcn = createCallbackFcn(app, @FCTRLPushed, true);
            app.FCTRL.Text = 'Fine Ctrl';
            app.DeltaWorldStatusPanel = uipanel(app.UIFigure);
            app.DeltaWorldStatusPanel.Title = 'DeltaWorld Status';
            app.DeltaStatus = uitextarea(app.DeltaWorldStatusPanel);
            app.DeltaStatus.ValueChangedFcn = createCallbackFcn(app, @DeltaStatusValueChanged, true);
            app.DeltaStatus.Editable = 'off';
            app.ControlsPanel = uipanel(app.UIFigure);
            app.ControlsPanel.Title = 'Controls';
            app.UndoButton = uibutton(app.ControlsPanel, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoButton.Text = 'Undo';
            app.RedoButton = uibutton(app.ControlsPanel, 'push');
            app.RedoButton.ButtonPushedFcn = createCallbackFcn(app, @RedoButtonPushed, true);
            app.RedoButton.Text = 'Redo';
            app.RefreshButton = uibutton(app.ControlsPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.ClearButton = uibutton(app.ControlsPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear ';
            app.Feedback = uipanel(app.UIFigure);
            app.Feedback.Title = 'Design Feedback';
            app.FOS = uitextarea(app.Feedback);
            app.FOS.ValueChangedFcn = createCallbackFcn(app, @FOSValueChanged, true);
            app.COST = uitextarea(app.Feedback);
            app.DoorLength = uitextarea(app.Feedback);
            app.Area = uitextarea(app.Feedback);
            app.Score = uitextarea(app.Feedback);
            app.FOSLabel = uilabel(app.Feedback);
            app.FOSLabel.Text = 'FOS';
            app.COSTLabel = uilabel(app.Feedback);
            app.COSTLabel.Text = 'COST';
            app.DoorLengthLabel = uilabel(app.Feedback);
            app.DoorLengthLabel.Text = 'Door Length';
            app.AreaLabel = uilabel(app.Feedback);
            app.AreaLabel.Text = 'Area';
            app.ScoreLabel = uilabel(app.Feedback);
            app.ScoreLabel.Text = 'Score';
            app.ThermalLabel = uilabel(app.Feedback);
            app.ThermalLabel.Text = 'Thermal';
            app.thermal = uitextarea(app.Feedback);
            app.AestheticsLabel = uilabel(app.Feedback);
            app.AestheticsLabel.Text = 'Aesthetics';
            app.aesthetics = uitextarea(app.Feedback);
            app.Details = uitextarea(app.UIFigure);
            app.Details.ValueChangedFcn = createCallbackFcn(app, @DetailsValueChanged, true);
            app.EndStudyButton = uibutton(app.UIFigure, 'push');
            app.EndStudyButton.ButtonPushedFcn = createCallbackFcn(app, @EndStudyButtonPushed, true);
            app.EndStudyButton.Text = 'End Study';
            app.Panel = uipanel(app.UIFigure);
            app.Label = uilabel(app.Panel);
            app.Label.Text = {'1. Minimum Interior Area of 100 QDs'; '2. Smoother Exterior and an Angular Interior'; '3. Doorway length must be between 5 and 10m'; ''};
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = {'4. Blue deltas may not exceed 60% of the total number of deltas. '; '5. No more than three blue deltas should be placed next to each other'};
            app.MinimumRequirementsandSuggestionsLabel = uilabel(app.Panel);
            app.MinimumRequirementsandSuggestionsLabel.Text = '       Minimum Requirements and Suggestions';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = {'6. cannot more than two red deltas next to one another'; '7. Factor of Safety (FOS) > 1 (We recommend 1.3)'; '8. Cost should be less than $1500'};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Picture3.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DeltaDesign(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end